classdef Laserapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaTInt                          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PhotonDistributionTab           matlab.ui.container.Tab
        LoadPhotonSettings              matlab.ui.control.Button
        NewPhotonSettings               matlab.ui.control.Button
        LabelPhotonSetting              matlab.ui.control.Label
        SavePhotonAs                    matlab.ui.control.Button
        SavePhoton                      matlab.ui.control.Button
        PhysicalParametersPanel_2       matlab.ui.container.Panel
        LayersListBoxLabel              matlab.ui.control.Label
        LayersListBox                   matlab.ui.control.ListBox
        BeamtypeLabel                   matlab.ui.control.Label
        BeamtypeDropDown                matlab.ui.control.DropDown
        BeamradiusmEditFieldLabel       matlab.ui.control.Label
        BeamradiusmEditField            matlab.ui.control.NumericEditField
        SubjectRadiusmEditFieldLabel    matlab.ui.control.Label
        SubjectRadiusmEditField         matlab.ui.control.NumericEditField
        RefractiveIndexnEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexnEditField       matlab.ui.control.NumericEditField
        Absorptioncoefficientacm1EditFieldLabel  matlab.ui.control.Label
        Absorptioncoefficientacm1EditField  matlab.ui.control.NumericEditField
        Scatteringcoefficientscm1EditFieldLabel  matlab.ui.control.Label
        Scatteringcoefficientscm1EditField  matlab.ui.control.NumericEditField
        AnisotropygEditFieldLabel       matlab.ui.control.Label
        AnisotropygEditField            matlab.ui.control.NumericEditField
        ThicknessdmEditFieldLabel       matlab.ui.control.Label
        ThicknessdmEditField            matlab.ui.control.NumericEditField
        RefractiveIndexLaserMediumnEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexLaserMediumnEditField  matlab.ui.control.NumericEditField
        RefractiveIndexMediumBehindnEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexMediumBehindnEditField  matlab.ui.control.NumericEditField
        AddLayerButton                  matlab.ui.control.Button
        DeleteLayerButton               matlab.ui.control.Button
        SimulationParametersPanel       matlab.ui.container.Panel
        NumberofPhotonsEditFieldLabel   matlab.ui.control.Label
        NumberofPhotonsEditField        matlab.ui.control.NumericEditField
        dzmEditFieldLabel               matlab.ui.control.Label
        dzmEditField                    matlab.ui.control.NumericEditField
        drmEditFieldLabel               matlab.ui.control.Label
        drmEditField                    matlab.ui.control.NumericEditField
        CalculatePhotonDistributionButton  matlab.ui.control.Button
        LabelPhotonCalc                 matlab.ui.control.Label
        HeatTab                         matlab.ui.container.Tab
        SetPhotonDistributionDataButton  matlab.ui.control.Button
        LabelPhotonDist                 matlab.ui.control.Label
        SaveAsButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        TableSubjectData                matlab.ui.control.Table
        LoadHeatButton                  matlab.ui.control.Button
        LabelHeatSetting                matlab.ui.control.Label
        NewHeatSettingsButton           matlab.ui.control.Button
        PhysicalParametersPanel         matlab.ui.container.Panel
        TableSubjectDataSet             matlab.ui.control.Table
        EvaluationTimemsEditFieldLabel  matlab.ui.control.Label
        EvaluationTimemsEditField       matlab.ui.control.NumericEditField
        StimulationTimemsEditFieldLabel  matlab.ui.control.Label
        StimulationTimemsEditField      matlab.ui.control.NumericEditField
        PulseDurationmsEditFieldLabel   matlab.ui.control.Label
        PulseDurationmsEditField        matlab.ui.control.NumericEditField
        PulseFrequencyHzEditFieldLabel  matlab.ui.control.Label
        PulseFrequencyHzEditField       matlab.ui.control.NumericEditField
        PulsePowermWEditFieldLabel      matlab.ui.control.Label
        PulsePowermWEditField           matlab.ui.control.NumericEditField
        TambientKEditFieldLabel         matlab.ui.control.Label
        TambientKEditField              matlab.ui.control.NumericEditField
        EvaluationRadiusmLabel          matlab.ui.control.Label
        EvaluationRadiusmEditField      matlab.ui.control.NumericEditField
        EvaluationThicknessmEditFieldLabel  matlab.ui.control.Label
        EvaluationThicknessmEditField   matlab.ui.control.NumericEditField
        BoundaryConditionDropDownLabel  matlab.ui.control.Label
        BoundaryConditionDropDown       matlab.ui.control.DropDown
        NumericalParametersPanel        matlab.ui.container.Panel
        MaxGridSizemEditFieldLabel      matlab.ui.control.Label
        MaxGridSizemEditField           matlab.ui.control.NumericEditField
        SubjectRadiusLabel              matlab.ui.control.Label
        SubjectDepthLabel               matlab.ui.control.Label
        SolveHeatEquationButton         matlab.ui.control.Button
        LabelHeatResult                 matlab.ui.control.Label
        VisualizationTab                matlab.ui.container.Tab
        PlottingSettingsPanel           matlab.ui.container.Panel
        LoadVisualizationSettingsButton  matlab.ui.control.Button
        AvailablePlotsListBoxLabel      matlab.ui.control.Label
        AvailablePlotsListBox           matlab.ui.control.ListBox
        NewButton                       matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        GenerateallPlotsButton          matlab.ui.control.Button
        SaveAsButton_2                  matlab.ui.control.Button
        NewVisualizationSettingsButton  matlab.ui.control.Button
        SelectheatresultdataButton      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        LabelVisualizationSetting       matlab.ui.control.Label
        LabelHeatDataSet                matlab.ui.control.Label
        SaveVisualization               matlab.ui.control.Button
        PlotPropertiesPanel             matlab.ui.container.Panel
        xAxisButtonGroup                matlab.ui.container.ButtonGroup
        RadiusrButton                   matlab.ui.control.RadioButton
        DepthzButton                    matlab.ui.control.RadioButton
        TimetButton                     matlab.ui.control.RadioButton
        MultiplePlotsListBoxLabel       matlab.ui.control.Label
        MultiplePlotsListBox            matlab.ui.control.ListBox
        NewButton_2                     matlab.ui.control.Button
        DeleteButton_2                  matlab.ui.control.Button
        IncludePlotinGenerationCheckBox  matlab.ui.control.CheckBox
        xAxisupperLimitEditFieldLabel   matlab.ui.control.Label
        xAxisupperLimitEditField        matlab.ui.control.NumericEditField
        xAxislowerLimitEditFieldLabel   matlab.ui.control.Label
        xAxislowerLimitEditField        matlab.ui.control.NumericEditField
        TAxisupperLimitEditFieldLabel   matlab.ui.control.Label
        TAxisupperLimitEditField        matlab.ui.control.NumericEditField
        TAxislowerLimitLabel            matlab.ui.control.Label
        TAxislowerLimitEditField        matlab.ui.control.NumericEditField
        xAxUpAuto                       matlab.ui.control.CheckBox
        TAxLowAuto                      matlab.ui.control.CheckBox
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        UpdatePreviewButton             matlab.ui.control.Button
        xAxLowAuto                      matlab.ui.control.CheckBox
        TAxUpAuto                       matlab.ui.control.CheckBox
        ValueSection1                   matlab.ui.control.NumericEditField
        ValueSection2                   matlab.ui.control.NumericEditField
        LabelSection1                   matlab.ui.control.Label
        LabelSection2                   matlab.ui.control.Label
        UnitSection1                    matlab.ui.control.Label
        UnitSection2                    matlab.ui.control.Label
        PlotPreviewPanel                matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        DebugPanel                      matlab.ui.container.Panel
        DirApp                          matlab.ui.control.Label
        DirData                         matlab.ui.control.Label
        DirectoryAppLabel               matlab.ui.control.Label
        DirectoryDataLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        sPhotonSetting
        sHeatEqSetting
        vHeatResult % solution of the heat equation
        sVisualizationSetting
        sPlotProperties
        vIndCurrPlot
        vIndCurrSection
        vHeatDataLoaded  = 0;
        vHeatDataPath
        vTinit
        vdTmax
        vtmax
        vPhotonDistPath
        vIndCurrLayer
        vPathUtilities
    end

    methods (Access = private)
        function Updateplotproperties(app)
            % set the displayed name of the current plot
            app.NameEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pName;
            % set the value if plot shall be evaluated
            app.IncludePlotinGenerationCheckBox.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pActive;
            % set the selected x-Axis           
            switch app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXAxis
                case 'Time t'
                    app.TimetButton.Value = 1;
                case 'Radius r'
                    app.RadiusrButton.Value = 1;
                case 'Depth z'
                    app.DepthzButton.Value = 1;
            end
            UpdateSectionLabels(app);        

%             app.xAxisButtonGroup.SelectedObject  = app.sVisualizationSetting.sPlotProperties(ind).pXAxis;

            % generate the list of available sections and select the first
            % element
            vNoSections = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections;
            indlist = num2cell(1:vNoSections);
            temp = strcat(cellfun(@num2str,indlist,'un',0),{'.) '},...
                {' '},{app.LabelSection1.Text},{' '},...
                cellfun(@num2str,num2cell([app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1]),'un',0),...
                {app.UnitSection1.Text},{', '},{app.LabelSection2.Text},{' '},...
                cellfun(@num2str,num2cell([app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2]),'un',0),...
                {app.UnitSection2.Text });
            app.MultiplePlotsListBox.Items = temp;
            app.MultiplePlotsListBox.Value = temp(app.vIndCurrSection);
            app.ValueSection1.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1(app.vIndCurrSection);
            app.ValueSection2.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2(app.vIndCurrSection);

            app.xAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp;
            app.xAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow;
            app.TAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp;
            app.TAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow;
            app.xAxUpAuto.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUpAuto;  
            app.xAxisupperLimitEditField.Editable = ~app.xAxUpAuto.Value;
            app.xAxLowAuto.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLowAuto;
            app.xAxislowerLimitEditField.Editable = ~app.xAxLowAuto.Value;
            app.TAxUpAuto.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUpAuto;
            app.TAxisupperLimitEditField.Editable = ~app.TAxUpAuto.Value;
            app.TAxLowAuto.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLowAuto;
            app.TAxislowerLimitEditField.Editable = ~app.TAxLowAuto.Value;
            app.xAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp;
            app.xAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow;
            app.TAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp;
            app.TAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow;         
        end
        
        function Updateplots(app,ind)
            
            noPlots = app.sVisualizationSetting.vNoPlots;
            indlist = num2cell(1:noPlots);
            temp = strcat(cellfun(@num2str,indlist,'un',0),{' '},{app.sVisualizationSetting.sPlotProperties.pName});
            app.AvailablePlotsListBox.Items = temp;
            app.AvailablePlotsListBox.Value = temp(ind);
%             app.AvailablePlotsListBox.ItemData = [1:]
            app.vIndCurrPlot = cell2mat(indlist(strcmp(app.AvailablePlotsListBox.Value,app.AvailablePlotsListBox.Items)));
        end
        
        function Newplot(app,ind)
            app.sVisualizationSetting.sPlotProperties(ind).pName = ['Default',num2str(ind)];
            app.sVisualizationSetting.sPlotProperties(ind).pType = 1;
            app.sVisualizationSetting.sPlotProperties(ind).pActive = 1;
            app.sVisualizationSetting.sPlotProperties(ind).pXAxis = app.RadiusrButton;
            app.sVisualizationSetting.sPlotProperties(ind).vNoSections = 1;
            app.sVisualizationSetting.sPlotProperties(ind).pSection1 = 0;
            app.sVisualizationSetting.sPlotProperties(ind).pSection2 = 0;
            app.sVisualizationSetting.sPlotProperties(ind).pXLimLow = 0;
            app.sVisualizationSetting.sPlotProperties(ind).pXLimLowAuto = 1;
            app.sVisualizationSetting.sPlotProperties(ind).pXLimUp = 0;
            app.sVisualizationSetting.sPlotProperties(ind).pXLimUpAuto = 1;
            app.sVisualizationSetting.sPlotProperties(ind).pTLimLow = 0;
            app.sVisualizationSetting.sPlotProperties(ind).pTLimLowAuto = 1;
            app.sVisualizationSetting.sPlotProperties(ind).pTLimUp = 0;
            app.sVisualizationSetting.sPlotProperties(ind).pTLimUpAuto = 1;
            app.sVisualizationSetting.vNoPlots = ind;
            Updateplots(app,ind);
            app.vIndCurrSection = 1;
            Updateplotproperties(app);

        end
        function UpdateSectionLabels(app)
            
            switch app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXAxis
                case 'Time t'
                    app.LabelSection1.Text = 'r =';
                    app.LabelSection2.Text = 'z =';
                    app.UnitSection1.Text = [char(181),'m'];
                    app.UnitSection2.Text = [char(181),'m'];
                case 'Radius r'
                    app.LabelSection1.Text = 'z =';
                    app.LabelSection2.Text = 't =';
                    app.UnitSection1.Text = [char(181),'m'];
                    app.UnitSection2.Text = 'ms';
                case 'Depth z'
                    app.LabelSection1.Text = 'r =';
                    app.LabelSection2.Text = 't =';
                    app.UnitSection1.Text = [char(181),'m'];
                    app.UnitSection2.Text = 'ms';
            end
        end
        
        function ReadPhotonDistParams(app)
            tempsPhotonSetting = load([app.sHeatEqSetting.pPhotonDistPath, app.sHeatEqSetting.pPhotonDistFile],'-mat','tempsPhotonSetting');
            tempsPhotonSetting = tempsPhotonSetting.tempsPhotonSetting;
            fileout = strsplit(tempsPhotonSetting.pFileName,'.');
            
            
            app.sHeatEqSetting.sParams.fileout=[fileout{1},'.mco'];
            app.sHeatEqSetting.sParams.fileoutPath = app.sHeatEqSetting.pPhotonDistPath;
            app.sHeatEqSetting.sParams.nophotons   = tempsPhotonSetting.sParams.pNoPhotons;
            app.sHeatEqSetting.sParams.beamtype = tempsPhotonSetting.sParams.pBeamtype;
            app.sHeatEqSetting.sParams.radius = tempsPhotonSetting.sParams.pBeamradius;
            
            app.sHeatEqSetting.sParams.dz       = tempsPhotonSetting.sParams.pdz*100; %[cm]
            app.sHeatEqSetting.sParams.dzm      = app.sHeatEqSetting.sParams.dz/100;
            app.sHeatEqSetting.sParams.dr       = tempsPhotonSetting.sParams.pdr*100;% [cm]
            app.sHeatEqSetting.sParams.drm      = app.sHeatEqSetting.sParams.dr/100;
            app.sHeatEqSetting.sParams.noz      = tempsPhotonSetting.sParams.pnoz;
            app.sHeatEqSetting.sParams.nor      = tempsPhotonSetting.sParams.pnor;
            app.sHeatEqSetting.sParams.nlayer   = tempsPhotonSetting.sParams.pnolayers;
            app.sHeatEqSetting.sParams.nlaser   = tempsPhotonSetting.sParams.pnlaser;
            app.sHeatEqSetting.sParams.nmedium  = tempsPhotonSetting.sParams.pn;
            app.sHeatEqSetting.sParams.mua      = tempsPhotonSetting.sParams.pmua;
            app.sHeatEqSetting.sParams.mus      = tempsPhotonSetting.sParams.pmus;
            app.sHeatEqSetting.sParams.ganiso        = tempsPhotonSetting.sParams.pg;
            app.sHeatEqSetting.sParams.dicke    = tempsPhotonSetting.sParams.pd;
            app.sHeatEqSetting.sParams.nluft    = tempsPhotonSetting.sParams.pnbehind;
%             app.sHeatEqSetting.sParams.refl =  (app.sHeatEqSetting.sParams.nmedium(1) - app.sHeatEqSetting.sParams.nlaser)^2/(app.sHeatEqSetting.sParams.nmedium(1) + app.sHeatEqSetting.sParams.nlaser)^2;
            
        
        %% case mco
%             fileID = fopen([app.sHeatEqSetting.pPhotonDistPath, app.sHeatEqSetting.pPhotonDistFile]);
%             C = textscan(fileID,'%s ',2,'TreatAsEmpty',{'NA','na'},'CommentStyle','#');
%             app.sHeatEqSetting.sParams.fileout=textscan(fileID,'%s  %*[^\n]',1,'TreatAsEmpty',{'NA','na'},'CommentStyle','#');
%             app.sHeatEqSetting.sParams.fileout =app.sHeatEqSetting.sParams.fileout{1}{1};
%             app.sHeatEqSetting.sParams.fileoutPath = app.sHeatEqSetting.pPhotonDistPath;
%             app.sHeatEqSetting.sParams.nophotons   = cell2mat(textscan(fileID,' %f ',1,'CommentStyle','#'));
%             app.sHeatEqSetting.sParams.beamtype = textscan(fileID,' %s',1,'CommentStyle','#'); % 
%             if( strcmp(app.sHeatEqSetting.sParams.beamtype{1},'f') || strcmp(app.sHeatEqSetting.sParams.beamtype{1},'g'))
%                app.sHeatEqSetting.sParams.radius = cell2mat(textscan(fileID,' %f',1,'CommentStyle','#')); % [cm]
%             elseif(  strcmp(app.sHeatEqSetting.sParams.beamtype{1},'h'))
%                 app.sHeatEqSetting.sParams.radius = cell2mat(textscan(fileID,' %f',1,'CommentStyle','#')); % [cm]
%                 app.sHeatEqSetting.sParams.focusdepth = cell2mat(textscan(fileID,' %f',1,'CommentStyle','#')); % [cm]
%             end    
%             
%             app.sHeatEqSetting.sParams.dz          = cell2mat(textscan(fileID,' %f',1,'CommentStyle','#')); % [cm]
%             app.sHeatEqSetting.sParams.dzm = app.sHeatEqSetting.sParams.dz/100;
%             app.sHeatEqSetting.sParams.dr = cell2mat(textscan(fileID,' %f ',1,'CommentStyle','#'));% [cm]
%             app.sHeatEqSetting.sParams.drm = app.sHeatEqSetting.sParams.dr/100;
%             
%             app.sHeatEqSetting.sParams.noz = cell2mat(textscan(fileID,' %f ',1,'CommentStyle','#'));
%             
%             app.sHeatEqSetting.sParams.nor= cell2mat(textscan(fileID,' %f %*[^\n]',1,'CommentStyle','#'));
%             app.sHeatEqSetting.sParams.nlayer = cell2mat(textscan(fileID,' %d ',1,'CommentStyle','#'));
%             app.sHeatEqSetting.sParams.nlaser      = cell2mat(textscan(fileID,' %f ',1,'CommentStyle','#'));
%             app.sHeatEqSetting.sParams.nmedium = zeros(app.sHeatEqSetting.sParams.nlayer,1);
%             app.sHeatEqSetting.sParams.mua=app.sHeatEqSetting.sParams.nmedium;
%             app.sHeatEqSetting.sParams.mus=app.sHeatEqSetting.sParams.mua;
%             app.sHeatEqSetting.sParams.g=app.sHeatEqSetting.sParams.mua;
%             app.sHeatEqSetting.sParams.dicke=app.sHeatEqSetting.sParams.mua;
%             for jj = 1:app.sHeatEqSetting.sParams.nlayer
%                 app.sHeatEqSetting.sParams.nmedium(jj)=cell2mat(textscan(fileID,'%f',1,'CommentStyle','#'));
%                 app.sHeatEqSetting.sParams.mua(jj)=cell2mat(textscan(fileID,'%f',1,'CommentStyle','#')); %[cm^-1]
%                 app.sHeatEqSetting.sParams.mus(jj)=cell2mat(textscan(fileID,'%f',1,'CommentStyle','#'));%[cm^-1]
%                 app.sHeatEqSetting.sParams.ganiso(jj) = cell2mat(textscan(fileID,'%f',1,'CommentStyle','#'));
%                 app.sHeatEqSetting.sParams.dicke(jj)= cell2mat(textscan(fileID,'%f',1,'CommentStyle','#'))/100;% [m]
%             end
%             app.sHeatEqSetting.sParams.nluft = cell2mat(textscan(fileID,'%f',1,'CommentStyle','#'));
%             app.sHeatEqSetting.sParams.refl =  (app.sHeatEqSetting.sParams.nmedium(1) - app.sHeatEqSetting.sParams.nlaser)^2/(app.sHeatEqSetting.sParams.nmedium(1) + app.sHeatEqSetting.sParams.nlaser)^2;
            

            app.TableSubjectData.Data = [];
            app.TableSubjectData.Data(:,1) = app.sHeatEqSetting.sParams.nmedium;
            app.TableSubjectData.Data(:,2) = app.sHeatEqSetting.sParams.mua;
            app.TableSubjectData.Data(:,3) = app.sHeatEqSetting.sParams.mus;
            app.TableSubjectData.Data(:,4) = app.sHeatEqSetting.sParams.ganiso;
            app.TableSubjectData.Data(:,5) = app.sHeatEqSetting.sParams.dicke;


            indlist = num2cell(1:app.sHeatEqSetting.sParams.nlayer);
            app.TableSubjectData.RowName = strcat({'layer '},cellfun(@num2str,indlist,'un',0));
            app.TableSubjectDataSet.RowName = strcat({'layer '},cellfun(@num2str,indlist,'un',0));
            app.SubjectRadiusLabel.Text = ['Subject Radius (µm): ', num2str(app.sHeatEqSetting.sParams.nor * app.sHeatEqSetting.sParams.drm*1e6) ];
            app.SubjectDepthLabel.Text = ['Subject Depth (µm): ', num2str(app.sHeatEqSetting.sParams.noz * app.sHeatEqSetting.sParams.dzm*1e6)];

        end
        
        
        function UpdateHeatEqSettings(app)
            app.EvaluationTimemsEditField.Value =   1000*app.sHeatEqSetting.sParams.tfinal;
            app.EvaluationRadiusmEditField.Value =  1e6*app.sHeatEqSetting.sParams.rmax;
            app.EvaluationThicknessmEditField.Value =   1e6*app.sHeatEqSetting.sParams.zmax;   
            app.StimulationTimemsEditField.Value =  1000*app.sHeatEqSetting.sParams.stimulationtime;
            app.PulsePowermWEditField.Value =       1000*app.sHeatEqSetting.sParams.beampower;
            app.PulseDurationmsEditField.Value =    1000*app.sHeatEqSetting.sParams.pulsetime;
            app.PulseFrequencyHzEditField.Value =   app.sHeatEqSetting.sParams.pulsefrequency;
            app.TambientKEditField.Value =          app.sHeatEqSetting.sParams.Tambient;
            app.BoundaryConditionDropDown.Value =   app.BoundaryConditionDropDown.Items(app.sHeatEqSetting.sParams.BC+1);
            app.MaxGridSizemEditField.Value =       1e6*app.sHeatEqSetting.sParams.Hmax;
            
            
            app.LabelHeatSetting.Text = app.sHeatEqSetting.pFileName;
            
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,1) = app.sHeatEqSetting.sParams.cheatmedium;
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,2) = app.sHeatEqSetting.sParams.rhomedium;
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,3) = app.sHeatEqSetting.sParams.lambdamedium;
            
            
        end
        
        function UpdateLayers(app)
            indlist = num2cell(1:app.sPhotonSetting.sParams.pnolayers);
            temp = strcat(cellfun(@num2str,indlist,'un',0),{'.) '},...
                {' n = '},...
                cellfun(@num2str,num2cell([app.sPhotonSetting.sParams.pn]),'un',0),...
                {', µa ='},...
                cellfun(@num2str,num2cell([app.sPhotonSetting.sParams.pmua]),'un',0),...
                {'cm^-1 , µs ='},...
                cellfun(@num2str,num2cell([app.sPhotonSetting.sParams.pmus]),'un',0),...
                {'cm^-1 , g ='},...
                cellfun(@num2str,num2cell([app.sPhotonSetting.sParams.pg]),'un',0),...
                {', d ='},...
                cellfun(@num2str,num2cell(1e6*app.sPhotonSetting.sParams.pd),'un',0),...
                {'µm'});
            app.LayersListBox.Items = temp;
            app.LayersListBox.Value = temp(app.vIndCurrLayer); 
        
        end
        
        function InitializePhotonSetting(app)
            app.vIndCurrLayer = 1;
            app.sPhotonSetting.pFilePath = '';
            app.sPhotonSetting.pFileName = '- No Data Set - ';
            app.sPhotonSetting.sParams.pNoPhotons = 1e9;
            app.sPhotonSetting.sParams.pnolayers = 1;
            app.sPhotonSetting.sParams.pdz = 2e-6;
            app.sPhotonSetting.sParams.pdr = 10e-6;
            app.sPhotonSetting.sParams.pBeamtype = 'Gaussian';
            app.sPhotonSetting.sParams.pBeamradius = 35e-6;
            app.sPhotonSetting.sParams.pnor = 1500;
            app.sPhotonSetting.sParams.pnlaser = 1.000273330 ;
            app.sPhotonSetting.sParams.pnbehind = 1.000273330 ;
            app.sPhotonSetting.sParams.pn = 1.3195;
            app.sPhotonSetting.sParams.pmua = 10.226;
            app.sPhotonSetting.sParams.pmus = 10;
            app.sPhotonSetting.sParams.pg = 0.8;
            app.sPhotonSetting.sParams.pd = 400e-6;
            UpdatePhotonLabels(app);
        end 
        function UpdatePhotonLabels(app)
            app.LabelPhotonSetting.Text         = app.sPhotonSetting.pFileName;
            app.NumberofPhotonsEditField.Value  =    app.sPhotonSetting.sParams.pNoPhotons;
            
            app.dzmEditField.Value =     1e6*app.sPhotonSetting.sParams.pdz;
            app.drmEditField.Value =     1e6*app.sPhotonSetting.sParams.pdr;
            app.BeamtypeDropDown.Value =     app.sPhotonSetting.sParams.pBeamtype;
            app.BeamradiusmEditField.Value =     1e6*app.sPhotonSetting.sParams.pBeamradius;
            app.SubjectRadiusmEditField.Value =     1e6*app.sPhotonSetting.sParams.pdr*app.sPhotonSetting.sParams.pnor;
            app.RefractiveIndexLaserMediumnEditField.Value = app.sPhotonSetting.sParams.pnlaser;
            app.RefractiveIndexMediumBehindnEditField.Value =     app.sPhotonSetting.sParams.pnbehind;
            UpdateLayers(app);
            app.RefractiveIndexnEditField.Value = app.sPhotonSetting.sParams.pn(app.vIndCurrLayer);
            app.Absorptioncoefficientacm1EditField.Value = app.sPhotonSetting.sParams.pmua(app.vIndCurrLayer);
            app.Scatteringcoefficientscm1EditField.Value = app.sPhotonSetting.sParams.pmus(app.vIndCurrLayer);
            app.AnisotropygEditField.Value = app.sPhotonSetting.sParams.pg(app.vIndCurrLayer);
            app.ThicknessdmEditField.Value = 1e6*app.sPhotonSetting.sParams.pd(app.vIndCurrLayer);
            
        end
        function InitializeHeatSetting(app)
            app.sHeatEqSetting.sParams.tfinal = 1;
            app.sHeatEqSetting.sParams.rmax = 1000e-6;
            app.sHeatEqSetting.sParams.zmax = 1000e-6;
            app.sHeatEqSetting.sParams.stimulationtime = 1;
            app.sHeatEqSetting.sParams.beampower = 30e-3;
            app.sHeatEqSetting.sParams.pulsetime = 5e-3;
            app.sHeatEqSetting.sParams.pulsefrequency = 0;
            app.sHeatEqSetting.sParams.Tambient = 293;
            app.sHeatEqSetting.sParams.Hmax = 5e-6;
            app.sHeatEqSetting.sParams.BC = 0;


        end 
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitializePhotonSetting(app);
            InitializeHeatSetting(app);
            app.vPathUtilities = pwd;
            app.DirApp.Text = pwd;
        end

        % Button pushed function: SelectheatresultdataButton
        function SelectheatresultdataButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.hes','Select file with parameters of heat equation.');
                app.vHeatDataPath = path;
                app.LabelHeatDataSet.Text = file;
                app.sVisualizationSetting.pHeatDataPath = path;
                
                app.sVisualizationSetting.pHeatDataFile=file;
            catch ME
                errorhandler(app,ME);
            end
            
            
        end

        % Button pushed function: NewVisualizationSettingsButton
        function NewVisualizationSettingsButtonPushed(app, event)
            app.sVisualizationSetting= [];
            
            app.sVisualizationSetting.vNoPlots = 0;
            app.sVisualizationSetting.pHeatDataFile = app.LabelHeatDataSet.Text;
            app.sVisualizationSetting.pHeatDataPath = app.vHeatDataPath;
            
            Newplot(app,1);
        
            try
                [file,path] = uiputfile('.vis');
                
                app.LabelVisualizationSetting.Text = file;
                app.sVisualizationSetting.pFileName = file;
                app.sVisualizationSetting.pFilePath = path;
                tempsVisualizationSetting = app.sVisualizationSetting;
                save([path,file],'tempsVisualizationSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end


        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            ind = app.sVisualizationSetting.vNoPlots+1;
            Newplot(app,ind);

        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pName = value;
            Updateplots(app,app.vIndCurrPlot);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot)=[];
            app.sVisualizationSetting.vNoPlots = app.sVisualizationSetting.vNoPlots-1;
            Updateplots(app,app.vIndCurrPlot-1);
        end

        % Value changed function: AvailablePlotsListBox
        function AvailablePlotsListBoxValueChanged(app, event)
            value = app.AvailablePlotsListBox.Value;
            app.vIndCurrPlot = strcmp(value,app.AvailablePlotsListBox.Items);
            Updateplots(app, app.vIndCurrPlot);
            app.vIndCurrSection = 1;
            Updateplotproperties(app);

        end

        % Button pushed function: SaveAsButton_2
        function SaveAsButton_2Pushed(app, event)
            try
                [file,path] = uiputfile('.vis');
                tempsVisualizationSetting = app.sVisualizationSetting;
                app.LabelVisualizationSetting.Text = file;
                app.sVisualizationSetting.pFileName = file;
                app.sVisualizationSetting.pFilePath = path;
                save([path,file],'tempsVisualizationSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
        end

        % Button pushed function: LoadVisualizationSettingsButton
        function LoadVisualizationSettingsButtonPushed(app, event)
            try
                [file,path] = uigetfile('.vis'); 
                load([path,file],'-mat','tempsVisualizationSetting');
                app.sVisualizationSetting = tempsVisualizationSetting;
                app.LabelVisualizationSetting.Text = app.sVisualizationSetting.pFileName;
                answer = questdlg('Apply Visualization on heat data currently loaded?','Heat Data','Yes','No', 'Yes');
                if strcmp(answer,'Yes')
                    app.sVisualizationSetting.pHeatDataFile = app.LabelHeatDataSet.Text;
                    app.sVisualizationSetting.pHeatData.Path = app.vHeatDataPath;
                else
                    app.LabelHeatDataSet.Text = app.sVisualizationSetting.pHeatDataFile;
                    app.vHeatDataPath = app.sVisualizationSetting.pHeatDataPath;
                    app.vHeatDataLoaded = 0;
                end
                
                
                Updateplots(app,1);
            catch ME
                errorhandler(app,ME);
            end


        end

        % Selection changed function: xAxisButtonGroup
        function xAxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.xAxisButtonGroup.SelectedObject;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXAxis = selectedButton.Text;
            Updateplotproperties(app);
        end

        % Value changed function: IncludePlotinGenerationCheckBox
        function IncludePlotinGenerationCheckBoxValueChanged(app, event)
            value = app.IncludePlotinGenerationCheckBox.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pActive = value;
        end

        % Button pushed function: NewButton_2
        function NewButton_2Pushed(app, event)
            app.vIndCurrSection = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections+1; 
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections =app.vIndCurrSection ;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1(app.vIndCurrSection) = 0;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2(app.vIndCurrSection) = 0;
            Updateplotproperties(app);
        end

        % Value changed function: xAxisupperLimitEditField
        function xAxisupperLimitEditFieldValueChanged(app, event)
            value = app.xAxisupperLimitEditField.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp  = value;
        end

        % Value changed function: xAxislowerLimitEditField
        function xAxislowerLimitEditFieldValueChanged(app, event)
            value = app.xAxislowerLimitEditField.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow  = value;
        end

        % Value changed function: TAxisupperLimitEditField
        function TAxisupperLimitEditFieldValueChanged(app, event)
            value = app.TAxisupperLimitEditField.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp  = value;
        end

        % Value changed function: TAxislowerLimitEditField
        function TAxislowerLimitEditFieldValueChanged(app, event)
            value = app.TAxislowerLimitEditField.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow  = value;
        end

        % Value changed function: xAxUpAuto
        function xAxUpAutoValueChanged(app, event)
            value = app.xAxUpAuto.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUpAuto  = value;
            app.xAxisupperLimitEditField.Editable = ~value;
            if app.vHeatDataLoaded && value
                
            end
        end

        % Value changed function: xAxLowAuto
        function xAxLowAutoValueChanged(app, event)
            value = app.xAxLowAuto.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLowAuto  = value;
            app.xAxislowerLimitEditField.Editable = ~value;
        end

        % Value changed function: TAxUpAuto
        function TAxUpAutoValueChanged(app, event)
            value = app.TAxUpAuto.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUpAuto  = value;
            app.TAxisupperLimitEditField.Editable = ~value;
            if value && app.vHeatDataLoaded
                app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp = app.vdTmax;
            end
        end

        % Value changed function: TAxLowAuto
        function TAxLowAutoValueChanged(app, event)
            value = app.TAxLowAuto.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLowAuto  = value;
            app.TAxislowerLimitEditField.Editable = ~value;
            if value && app.vHeatDataLoaded
                app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow = 0;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % ToDo display message warning that loading may take a while
            path = app.sVisualizationSetting.pHeatDataPath;
            filename = strsplit( app.sVisualizationSetting.pHeatDataFile,'.');
            filename = [filename{1},'.her'];

            output = load([path,filename],'-mat','output');
            app.vHeatResult = output.output.result;
            app.vHeatDataLoaded = 1;
            app.vTinit = output.output.thermalModelT.InitialConditions.ThermalICAssignments.InitialTemperature  ;
            T = app.vHeatResult.Temperature-app.vTinit;
            app.vdTmax = max(max(T));
            app.vtmax = max(app.vHeatResult.SolutionTimes);

        end

        % Button pushed function: UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            if app.vHeatDataLoaded == 0
                % make sure heat data is loaded
                LoadButtonPushed(app,event);          
            end

            rmax = max(app.vHeatResult.Mesh.Nodes(1,:));
            zmax = max(app.vHeatResult.Mesh.Nodes(2,:));
            switch app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXAxis
                case 'Time t'
                    
                    rlist = 1e-6*app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1;
                    zlist = 1e-6*app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2;
                    Cuintrp = interpolateTemperature(app.vHeatResult,rlist,zlist,1:length(app.vHeatResult.SolutionTimes))-...
                        app.vTinit;
                    legendnames = cell(length(zlist),1);
                    zshift = 0;
                    for ii = 1:length(zlist)
                        legendnames{ii} = ['r = ',num2str((rlist(ii))*1e6),' {\mu}m, ', 'z = ',num2str((zlist(ii)-zshift)*1e6),' {\mu}m'];
                    end
                    indnan =any(isnan(Cuintrp),2) ;
                    if(any(indnan))
                        Cuintrp(indnan,:)= interpolateTemperature(result,rlist(indnan),zlist(indnan)-1e-6,1:length(app.vHeatResult.SolutionTimes))-app.vTinit;
                        indnan =any(isnan(Cuintrp),2) ;
                        if (any(indnan))
                            Cuintrp(indnan,:)= interpolateTemperature(result,rlist(indnan),zlist(indnan)+1e-6,1:length(app.vHeatResult.SolutionTimes))-app.vTinit;
                        end
                    end
                    
                    indnan =any(isnan(Cuintrp),2) ;
                    if any(indnan)
%                         zlist(indnan) = [];
%                         xlist(indnan) = [];
                        legendnames(any(isnan(Cuintrp),2)) = [];  
                    end
                    xval = 1000*app.vHeatResult.SolutionTimes;
                    yval = Cuintrp;
                             
                    labelx = 't [ms]';
                    labely = '{\Delta}T [K]';
                    if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUpAuto 
                        app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp = 1000*app.vtmax;
                        app.xAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp;
                    end
                    if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLowAuto 
                        app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow = 0;
                        app.xAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow;
                    end
                case 'Radius r'
                    
                    vresolutionr = 1000;
                    
                    if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUpAuto 
                        app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp = rmax;
                        app.xAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp;
                    end
                    if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLowAuto 
                        app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow = 0;
                        app.xAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow;
                    end   
                    rlist = 1e-6*linspace(app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow,...
                         app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp,...
                         vresolutionr);
                    zlist = 1e-6*app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1;
                    tlist = 1e-3*app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2';
                    [xmesh,zmesh] = meshgrid(rlist,zlist);
                    Cuintrp = interpolateTemperature(app.vHeatResult,xmesh,zmesh,1:length(app.vHeatResult.SolutionTimes))-...
                        app.vTinit;
                    
                    vtempinterp = interp1(app.vHeatResult.SolutionTimes,Cuintrp',tlist,'linear');
                    xval = 1e6* rlist;
                    
                    yval = zeros(app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections,vresolutionr);
                    for ii=1:app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections
                        vtemp = reshape(vtempinterp(ii,:)',size(zmesh));
                        yval(ii,:) = vtemp(ii,:);  
                    end
                    labelx = 'r [{\mu}m]';
                    labely = '{\Delta}T [K]';
                    
                    legendnames = cell(length(zlist),1);
                    zshift = 0;
                    for ii = 1:length(zlist)
                        legendnames{ii} = ['z = ',num2str((zlist(ii)-zshift)*1e6),...
                            ' {\mu}m, t = ',...
                            num2str((tlist(ii))*1e3),' ms', ];
                    end
                case 'Depth z'
                    
                    vresolutionz = 1000;
                    
                    if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUpAuto 
                        app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp = zmax;
                        app.xAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp;
                    end
                    if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLowAuto 
                        app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow = 0;
                        app.xAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow;
                    end 
                    zlist = 1e-6*linspace(app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow,...
                         app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp,...
                         vresolutionz);
                    rlist = 1e-6*app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1;
                    tlist = 1e-3*app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2';
                    [xmesh,zmesh] = meshgrid(rlist,zlist);
                    Cuintrp = interpolateTemperature(app.vHeatResult,xmesh,zmesh,1:length(app.vHeatResult.SolutionTimes))-...
                        app.vTinit;
                    
                    vtempinterp = interp1(app.vHeatResult.SolutionTimes,Cuintrp',tlist,'linear');
                    xval = 1e6* zlist;
                    
                    yval = zeros(app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections,vresolutionz);
                    for ii=1:app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections
                        vtemp = reshape(vtempinterp(ii,:)',size(zmesh));
                        yval(ii,:) = vtemp(:,ii);
                    end
                    labelx = 'z [{\mu}m]';
                    labely = '{\Delta}T [K]';
                    
                    legendnames = cell(length(rlist),1);
                    zshift = 0;
                    for ii = 1:length(rlist)
                        legendnames{ii} = ['r = ',num2str((rlist(ii)-zshift)*1e6),...
                            ' {\mu}m, t = ',...
                            num2str((tlist(ii))*1e3),' ms', ];
                    end
               
            end
            
            plot(app.UIAxes,xval,yval);

            legend(app.UIAxes,legendnames);
            xlabel(app.UIAxes,labelx);
            ylabel(app.UIAxes,labely);
            if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUpAuto 
                app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp = app.vdTmax;
                app.TAxisupperLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp;      
            end
            if app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLowAuto 
                app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow = 0;
                app.TAxislowerLimitEditField.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow;                 
            end
         
            ylim(app.UIAxes,[app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimLow,...
                app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pTLimUp]);
            xlim(app.UIAxes,[app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimLow,...
                app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pXLimUp]);

        end

        % Value changed function: MultiplePlotsListBox
        function MultiplePlotsListBoxValueChanged(app, event)
            indlist = 1:app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).vNoSections;
            app.vIndCurrSection = indlist(strcmp(app.MultiplePlotsListBox.Value,app.MultiplePlotsListBox.Items));
            app.ValueSection1.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1(app.vIndCurrSection);
            app.ValueSection2.Value = app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2(app.vIndCurrSection);
            Updateplotproperties(app);
        end

        % Value changed function: ValueSection1
        function ValueSection1ValueChanged(app, event)
            value = app.ValueSection1.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection1(app.vIndCurrSection) = value;
            Updateplotproperties(app);
        end

        % Value changed function: ValueSection2
        function ValueSection2ValueChanged(app, event)
            value = app.ValueSection2.Value;
            app.sVisualizationSetting.sPlotProperties(app.vIndCurrPlot).pSection2(app.vIndCurrSection) = value;
            Updateplotproperties(app);
        end

        % Button pushed function: SetPhotonDistributionDataButton
        function SetPhotonDistributionDataButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.phs','Select file with photon distribution settings.');
            catch ME
                errorhandler(app,ME);
            end
            app.vPhotonDistPath = path;
            app.sHeatEqSetting.pPhotonDistPath = path;
            app.LabelPhotonDist.Text = file;
            app.sHeatEqSetting.pPhotonDistFile=file;
            ReadPhotonDistParams(app);
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,1) = 4190;
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,2) = 1000;
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,3) = 0.597;
            app.sHeatEqSetting.sParams.cheatmedium = app.TableSubjectDataSet.Data(:,1);
            app.sHeatEqSetting.sParams.rhomedium = app.TableSubjectDataSet.Data(:,2);
            app.sHeatEqSetting.sParams.lambdamedium = app.TableSubjectDataSet.Data(:,3);
        end

        % Button pushed function: NewHeatSettingsButton
        function NewHeatSettingsButtonPushed(app, event)
            app.sHeatEqSetting= [];

            app.sHeatEqSetting.pPhotonDistPath = app.vPhotonDistPath;
            app.sHeatEqSetting.pPhotonDistFile = app.LabelPhotonDist.Text;
            
            
            app.sHeatEqSetting.sParams.nlayer = size(app.TableSubjectData.Data,1);
            
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,1) = 4190;
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,2) = 1000;
            app.TableSubjectDataSet.Data(1:app.sHeatEqSetting.sParams.nlayer,3) = 0.597;
            app.sHeatEqSetting.sParams.cheatmedium = app.TableSubjectDataSet.Data(:,1);
            app.sHeatEqSetting.sParams.rhomedium = app.TableSubjectDataSet.Data(:,2);
            app.sHeatEqSetting.sParams.lambdamedium = app.TableSubjectDataSet.Data(:,3);
            %Initialize Heat Equation Parameters
            InitializeHeatSetting(app);
            
            try
                [file,path] = uiputfile('.hes');
                app.sHeatEqSetting.pFileName = file;
                app.sHeatEqSetting.pFilePath = path;
                app.LabelHeatSetting.Text = path;

                tempsHeatEqSetting = app.sHeatEqSetting;
                save([path,file],'tempsHeatEqSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end           
                        UpdateHeatEqSettings(app);

        end

        % Button pushed function: LoadHeatButton
        function LoadHeatButtonPushed(app, event)
            try
                [file,path] = uigetfile('.hes'); 
                load([path,file],'-mat','tempsHeatEqSetting');
                app.sHeatEqSetting = tempsHeatEqSetting;
%                 app.LabelHeatSetting.Text = app.sHeatEqSetting.pName;    

                answer = questdlg('Apply Heat Equation Settings on photon distribution currently loaded?','Head Data','Yes','No', 'Yes');
                if strcmp(answer,'Yes')
                    app.sHeatEqSetting.pPhotonDistPath = app.vPhotonDistPath;
                    app.sHeatEqSetting.pPhotonDistFile = app.LabelPhotonDist.Text;
                else
                    app.LabelPhotonDist.Text =  app.sHeatEqSetting.pPhotonDistFile;
                    app.vPhotonDistPath = app.sHeatEqSetting.pPhotonDistPath;
                end
                
                
                ReadPhotonDistParams(app);
                UpdateHeatEqSettings(app);
            catch ME
                errorhandler(app,ME);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                
                file = app.sHeatEqSetting.pFileName;
                path = app.sHeatEqSetting.pFilePath;
                tempsHeatEqSetting = app.sHeatEqSetting;
                save([path,file],'tempsHeatEqSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            try
                [file,path] = uiputfile('.hes');
                app.sHeatEqSetting.pFileName = file;
                app.sHeatEqSetting.pFilePath = path;
                app.LabelHeatSetting.Text = file;
                
                tempsHeatEqSetting = app.sHeatEqSetting;
                save([path,file],'tempsHeatEqSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
        end

        % Cell edit callback: TableSubjectDataSet
        function TableSubjectDataSetCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            
%             app.TableSubjectDataSet.Data(indices ) = newData;
            app.sHeatEqSetting.sParams.cheatmedium = app.TableSubjectDataSet.Data(:,1);
            app.sHeatEqSetting.sParams.rhomedium = app.TableSubjectDataSet.Data(:,2);
            app.sHeatEqSetting.sParams.lambdamedium = app.TableSubjectDataSet.Data(:,3);
        end

        % Value changed function: EvaluationTimemsEditField
        function EvaluationTimemsEditFieldValueChanged(app, event)
            value = app.EvaluationTimemsEditField.Value;
            app.sHeatEqSetting.sParams.tfinal = 1e-3*value;
        end

        % Value changed function: StimulationTimemsEditField
        function StimulationTimemsEditFieldValueChanged(app, event)
            value = app.StimulationTimemsEditField.Value;
            app.sHeatEqSetting.sParams.stimulationtime = 1e-3*value;
        end

        % Value changed function: PulsePowermWEditField
        function PulsePowermWEditFieldValueChanged(app, event)
            value = app.PulsePowermWEditField.Value;
            app.sHeatEqSetting.sParams.beampower = 1e-3*value;
        end

        % Value changed function: PulseDurationmsEditField
        function PulseDurationmsEditFieldValueChanged(app, event)
            value = app.PulseDurationmsEditField.Value;
            app.sHeatEqSetting.sParams.pulsetime = 1e-3*value;
        end

        % Value changed function: PulseFrequencyHzEditField
        function PulseFrequencyHzEditFieldValueChanged(app, event)
            value = app.PulseFrequencyHzEditField.Value;
            app.sHeatEqSetting.sParams.pulsefrequency = value;

        end

        % Value changed function: MaxGridSizemEditField
        function MaxGridSizemEditFieldValueChanged(app, event)
            value = app.MaxGridSizemEditField.Value;
            app.sHeatEqSetting.sParams.Hmax = 1e-6*value;
        end

        % Button pushed function: SolveHeatEquationButton
        function SolveHeatEquationButtonPushed(app, event)
            app.LabelHeatResult.Text = 'Calculating';
            output = SolveHeatEq(app.sHeatEqSetting.sParams);
                
            path = app.sHeatEqSetting.pFilePath;
            filename = strsplit(app.sHeatEqSetting.pFileName,'.');
            filename = [filename{1},'.her'];
            save([path,filename],'-mat','output'); 
            app.LabelHeatDataSet.Text = filename;
            app.sVisualizationSetting.pHeatDataPath = path;
               
            app.sVisualizationSetting.pHeatDataFile=filename;
            app.vHeatResult = output.result;
            app.vHeatDataLoaded = 1;
            app.vTinit = output.thermalModelT.InitialConditions.ThermalICAssignments.InitialTemperature  ;
            T = app.vHeatResult.Temperature-app.vTinit;
            app.vdTmax = max(max(T));
            app.vtmax = max(app.vHeatResult.SolutionTimes);
            app.LabelHeatResult.Text = 'Finished';
        end

        % Value changed function: TambientKEditField
        function TambientKEditFieldValueChanged(app, event)
            value = app.TambientKEditField.Value;
            app.sHeatEqSetting.sParams.Tambient = value;
        end

        % Value changed function: EvaluationRadiusmEditField
        function EvaluationRadiusmEditFieldValueChanged(app, event)
            value = app.EvaluationRadiusmEditField.Value;
            app.sHeatEqSetting.sParams.rmax = 1e-6*value;

        end

        % Value changed function: EvaluationThicknessmEditField
        function EvaluationThicknessmEditFieldValueChanged(app, event)
            value = app.EvaluationThicknessmEditField.Value;
            app.sHeatEqSetting.sParams.zmax = 1e-6*value;
        end

        % Value changed function: BoundaryConditionDropDown
        function BoundaryConditionDropDownValueChanged(app, event)
            value = app.BoundaryConditionDropDown.Value;
            app.BoundaryConditionDropDown.Items
            indlist = 1:size(app.BoundaryConditionDropDown.Items,2);
            app.sHeatEqSetting.sParams.BC = indlist(strcmp(value,app.BoundaryConditionDropDown.Items))-1;
            

        end

        % Button pushed function: SaveVisualization
        function SaveVisualizationButtonPushed(app, event)
            try
               
                tempsVisualizationSetting = app.sVisualizationSetting;
           
                file = app.sVisualizationSetting.pFileName;
                path = app.sVisualizationSetting.pFilePath;
                save([path,file],'tempsVisualizationSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
        end

        % Button pushed function: NewPhotonSettings
        function NewPhotonSettingsButtonPushed(app, event)
            InitializePhotonSetting(app)
            try
                [file,path] = uiputfile('.phs');
                app.sPhotonSetting.pFileName = file;
                app.sPhotonSetting.pFilePath = path;
                app.LabelPhotonSetting.Text = file;
                
                tempsPhotonSetting = app.sPhotonSetting;
                save([path,file],'tempsPhotonSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
            
        end

        % Button pushed function: LoadPhotonSettings
        function LoadPhotonSettingsButtonPushed(app, event)
            try
                [file,path] = uigetfile('.phs'); 
                load([path,file],'-mat','tempsPhotonSetting');
                app.sPhotonSetting = tempsPhotonSetting;
                app.LabelPhotonSetting.Text = app.sPhotonSetting.pFileName;    
            catch ME
                errorhandler(app,ME);
            end
            UpdatePhotonLabels(app);
        end

        % Button pushed function: SavePhotonAs
        function SavePhotonAsButtonPushed(app, event)
            try
                [file,path] = uiputfile('.phs');
                app.sPhotonSetting.pFileName = file;
                app.sPhotonSetting.pFilePath = path;
                app.LabelPhotonSetting.Text = file;
                
                tempsPhotonSetting = app.sPhotonSetting;
                save([path,file],'tempsPhotonSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
        end

        % Button pushed function: SavePhoton
        function SavePhotonButtonPushed(app, event)
            try
                file = app.sPhotonSetting.pFileName;
                path = app.sPhotonSetting.pFilePath;
                tempsPhotonSetting = app.sPhotonSetting;
                save([path,file],'tempsPhotonSetting','-mat');
            catch ME
                errorhandler(app,ME);
            end
        end

        % Value changed function: NumberofPhotonsEditField
        function NumberofPhotonsEditFieldValueChanged(app, event)
            value = app.NumberofPhotonsEditField.Value;
            app.sPhotonSetting.sParams.pNoPhotons = value;
        end

        % Value changed function: dzmEditField
        function dzmEditFieldValueChanged(app, event)
            value = app.dzmEditField.Value;
            app.sPhotonSetting.sParams.pdz = 1e-6*value;
            vthickness = sum(app.sPhotonSetting.sParams.pd);
            temp = app.sPhotonSetting.sParams.pd/app.sPhotonSetting.sParams.pdz;

            if round(temp) ~= temp
                f = uifigure;
                uialert(f,'Thickness no multiple of dz','Invalid dz');       
            else
                app.sPhotonSetting.sParams.pnoz = vthickness/app.sPhotonSetting.sParams.pdz;    
            end
        end

        % Value changed function: drmEditField
        function drmEditFieldValueChanged(app, event)
            value = app.drmEditField.Value;
            app.sPhotonSetting.sParams.pdr = 1e-6*value;
            app.sPhotonSetting.sParams.pnor = ceil(1e-6*app.SubjectRadiusmEditField.Value/app.sPhotonSetting.sParams.pdr);

            app.SubjectRadiusmEditField.Value = 1e6*app.sPhotonSetting.sParams.pnor*app.sPhotonSetting.sParams.pdr;

        end

        % Value changed function: BeamtypeDropDown
        function BeamtypeDropDownValueChanged(app, event)
            value = app.BeamtypeDropDown.Value;
            app.BeamtypeDropDown.Items
            indlist = 1:size(app.BeamtypeDropDown.Items,2);
            app.sPhotonSetting.sParams.pBeamtype = indlist(strcmp(value,app.BoundaryConditionDropDown.Items));
            
        end

        % Value changed function: BeamradiusmEditField
        function BeamradiusmEditFieldValueChanged(app, event)
            value = app.BeamradiusmEditField.Value;
            app.sPhotonSetting.sParams.pBeamradius = 1e-6*value;
        end

        % Value changed function: SubjectRadiusmEditField
        function SubjectRadiusmEditFieldValueChanged(app, event)
            value = app.SubjectRadiusmEditField.Value;
            app.sPhotonSetting.sParams.pnor = ceil(1e-6*value/app.sPhotonSetting.sParams.pdr);
            app.SubjectRadiusmEditField.Value = 1e6*app.sPhotonSetting.sParams.pnor*app.sPhotonSetting.sParams.pdr;
        end

        % Value changed function: 
        % RefractiveIndexLaserMediumnEditField
        function RefractiveIndexLaserMediumnEditFieldValueChanged(app, event)
            value = app.RefractiveIndexLaserMediumnEditField.Value;
            app.sPhotonSetting.sParams.pnlaser = value;

        end

        % Value changed function: 
        % RefractiveIndexMediumBehindnEditField
        function RefractiveIndexMediumBehindnEditFieldValueChanged(app, event)
            value = app.RefractiveIndexMediumBehindnEditField.Value;
            app.sPhotonSetting.sParams.pnbehind = value;

        end

        % Value changed function: LayersListBox
        function LayersListBoxValueChanged(app, event)
            value = app.LayersListBox.Value;
            indlist = 1:app.sPhotonSetting.sParams.pnolayers;
            app.vIndCurrLayer = indlist(strcmp(value,app.LayersListBox.Items));
            app.RefractiveIndexnEditField.Value             = app.sPhotonSetting.sParams.pn(app.vIndCurrLayer);
            app.Absorptioncoefficientacm1EditField.Value    = app.sPhotonSetting.sParams.pmua(app.vIndCurrLayer);
            app.Scatteringcoefficientscm1EditField.Value    = app.sPhotonSetting.sParams.pmus(app.vIndCurrLayer);
            app.AnisotropygEditField.Value                  = app.sPhotonSetting.sParams.pg(app.vIndCurrLayer);
            app.ThicknessdmEditField.Value                  = 1e6*app.sPhotonSetting.sParams.pd(app.vIndCurrLayer);
        end

        % Value changed function: RefractiveIndexnEditField
        function RefractiveIndexnEditFieldValueChanged(app, event)
            value = app.RefractiveIndexnEditField.Value;
            app.sPhotonSetting.sParams.pn(app.vIndCurrLayer) = value;
            UpdateLayers(app);
        end

        % Value changed function: Absorptioncoefficientacm1EditField
        function Absorptioncoefficientacm1EditFieldValueChanged(app, event)
            value = app.Absorptioncoefficientacm1EditField.Value;
            app.sPhotonSetting.sParams.pmua(app.vIndCurrLayer) = value;
            UpdateLayers(app);
        end

        % Value changed function: Scatteringcoefficientscm1EditField
        function Scatteringcoefficientscm1EditFieldValueChanged(app, event)
            value = app.Scatteringcoefficientscm1EditField.Value;
            app.sPhotonSetting.sParams.pmus(app.vIndCurrLayer) = value;
            UpdateLayers(app);
        end

        % Value changed function: AnisotropygEditField
        function AnisotropygEditFieldValueChanged(app, event)
            value = app.AnisotropygEditField.Value;
            app.sPhotonSetting.sParams.pg(app.vIndCurrLayer) = value;
            UpdateLayers(app);
        end

        % Value changed function: ThicknessdmEditField
        function ThicknessdmEditFieldValueChanged(app, event)
            value = app.ThicknessdmEditField.Value;
            app.sPhotonSetting.sParams.pd(app.vIndCurrLayer) = 1e-6*value;
            vthickness = sum(app.sPhotonSetting.sParams.pd);

            temp = app.sPhotonSetting.sParams.pd/app.sPhotonSetting.sParams.pdz;

            if round(temp) ~= temp
                f = uifigure;
                uialert(f,'Thickness no multiple of dz','Invalid dz');       
            else
                app.sPhotonSetting.sParams.pnoz = vthickness/app.sPhotonSetting.sParams.pdz;    
            end
            
            UpdateLayers(app);
        end

        % Button pushed function: AddLayerButton
        function AddLayerButtonPushed(app, event)
        
            app.sPhotonSetting.sParams.pn = [app.sPhotonSetting.sParams.pn,app.sPhotonSetting.sParams.pn(end)];
            app.sPhotonSetting.sParams.pmua = [app.sPhotonSetting.sParams.pmua,app.sPhotonSetting.sParams.pmua(end)];
            app.sPhotonSetting.sParams.pmus = [app.sPhotonSetting.sParams.pmus,app.sPhotonSetting.sParams.pmus(end)];
            app.sPhotonSetting.sParams.pg = [app.sPhotonSetting.sParams.pg,app.sPhotonSetting.sParams.pg(end)];
            app.sPhotonSetting.sParams.pd = [app.sPhotonSetting.sParams.pd,app.sPhotonSetting.sParams.pd(end)];
            app.sPhotonSetting.sParams.pnolayers = app.sPhotonSetting.sParams.pnolayers+1;
            app.vIndCurrLayer = app.sPhotonSetting.sParams.pnolayers;
            UpdateLayers(app);
            
        end

        % Button pushed function: DeleteLayerButton
        function DeleteLayerButtonPushed(app, event)
            if app.sPhotonSetting.sParams.pnolayers >1    
                app.sPhotonSetting.sParams.pnolayers = app.sPhotonSetting.sParams.pnolayers-1;
                
                app.sPhotonSetting.sParams.pn(app.vIndCurrLayer) = [];
                app.sPhotonSetting.sParams.pmua(app.vIndCurrLayer) = [];
                app.sPhotonSetting.sParams.pmus(app.vIndCurrLayer) = [];
                app.sPhotonSetting.sParams.pg(app.vIndCurrLayer) = [];
                app.sPhotonSetting.sParams.pd(app.vIndCurrLayer) = [];
                app.vIndCurrLayer = app.vIndCurrLayer-1;
                UpdateLayers(app);
            end
        end

        % Button pushed function: CalculatePhotonDistributionButton
        function CalculatePhotonDistributionButtonPushed(app, event)
            try
                app.LabelPhotonCalc.Text = 'Calculating';
                vthickness = sum(app.sPhotonSetting.sParams.pd);
                temp = app.sPhotonSetting.sParams.pd/app.sPhotonSetting.sParams.pdz;
                if round(temp) ~= temp
                    f = uifigure;
                    uialert(f,'Thickness no multiple of dz','Invalid dz');       
                    app.LabelPhotonCalc.Text = 'Error';
                else
                    app.sPhotonSetting.sParams.pnoz = vthickness/app.sPhotonSetting.sParams.pdz;    
                    generatePhotonDistribution(app.sPhotonSetting,app.vPathUtilities);
                    app.LabelPhotonCalc.Text = 'Finished';    
                end
            catch ME
                errorhandler(app,ME)
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaTInt = uifigure('Visible', 'off');
            app.LaTInt.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.LaTInt);
            app.PhotonDistributionTab = uitab(app.TabGroup);
            app.PhotonDistributionTab.Title = 'Photon Distribution';
            app.LoadPhotonSettings = uibutton(app.PhotonDistributionTab, 'push');
            app.LoadPhotonSettings.ButtonPushedFcn = createCallbackFcn(app, @LoadPhotonSettingsButtonPushed, true);
            app.LoadPhotonSettings.Text = 'Load Photon Settings';
            app.NewPhotonSettings = uibutton(app.PhotonDistributionTab, 'push');
            app.NewPhotonSettings.ButtonPushedFcn = createCallbackFcn(app, @NewPhotonSettingsButtonPushed, true);
            app.NewPhotonSettings.Text = 'New Photon Settings';
            app.LabelPhotonSetting = uilabel(app.PhotonDistributionTab);
            app.LabelPhotonSetting.Text = '- No Data Set - ';
            app.SavePhotonAs = uibutton(app.PhotonDistributionTab, 'push');
            app.SavePhotonAs.ButtonPushedFcn = createCallbackFcn(app, @SavePhotonAsButtonPushed, true);
            app.SavePhotonAs.Text = 'Save As...';
            app.SavePhoton = uibutton(app.PhotonDistributionTab, 'push');
            app.SavePhoton.ButtonPushedFcn = createCallbackFcn(app, @SavePhotonButtonPushed, true);
            app.SavePhoton.Text = 'Save';
            app.PhysicalParametersPanel_2 = uipanel(app.PhotonDistributionTab);
            app.PhysicalParametersPanel_2.Title = 'Physical Parameters';
            app.LayersListBoxLabel = uilabel(app.PhysicalParametersPanel_2);
            app.LayersListBoxLabel.Text = 'Layers';
            app.LayersListBox = uilistbox(app.PhysicalParametersPanel_2);
            app.LayersListBox.Items = {'Item 4'};
            app.LayersListBox.ValueChangedFcn = createCallbackFcn(app, @LayersListBoxValueChanged, true);
            app.LayersListBox.Value = 'Item 4';
            app.BeamtypeLabel = uilabel(app.PhysicalParametersPanel_2);
            app.BeamtypeLabel.Text = 'Beamtype';
            app.BeamtypeDropDown = uidropdown(app.PhysicalParametersPanel_2);
            app.BeamtypeDropDown.Items = {'Delta', 'Flat', 'Gaussian'};
            app.BeamtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BeamtypeDropDownValueChanged, true);
            app.BeamtypeDropDown.Value = 'Gaussian';
            app.BeamradiusmEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.BeamradiusmEditFieldLabel.Text = 'Beamradius (µm)';
            app.BeamradiusmEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.BeamradiusmEditField.ValueChangedFcn = createCallbackFcn(app, @BeamradiusmEditFieldValueChanged, true);
            app.SubjectRadiusmEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.SubjectRadiusmEditFieldLabel.Text = 'Subject Radius (µm)';
            app.SubjectRadiusmEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.SubjectRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectRadiusmEditFieldValueChanged, true);
            app.RefractiveIndexnEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.RefractiveIndexnEditFieldLabel.Text = 'Refractive Index n';
            app.RefractiveIndexnEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.RefractiveIndexnEditField.ValueChangedFcn = createCallbackFcn(app, @RefractiveIndexnEditFieldValueChanged, true);
            app.RefractiveIndexnEditField.Value = 1;
            app.Absorptioncoefficientacm1EditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.Absorptioncoefficientacm1EditFieldLabel.Text = 'Absorption coefficient µa (cm^-1)';
            app.Absorptioncoefficientacm1EditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.Absorptioncoefficientacm1EditField.ValueChangedFcn = createCallbackFcn(app, @Absorptioncoefficientacm1EditFieldValueChanged, true);
            app.Scatteringcoefficientscm1EditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.Scatteringcoefficientscm1EditFieldLabel.Text = 'Scattering coefficient µs (cm^-1)';
            app.Scatteringcoefficientscm1EditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.Scatteringcoefficientscm1EditField.ValueChangedFcn = createCallbackFcn(app, @Scatteringcoefficientscm1EditFieldValueChanged, true);
            app.AnisotropygEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.AnisotropygEditFieldLabel.Text = ' Anisotropy g';
            app.AnisotropygEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.AnisotropygEditField.ValueChangedFcn = createCallbackFcn(app, @AnisotropygEditFieldValueChanged, true);
            app.AnisotropygEditField.Value = 0.8;
            app.ThicknessdmEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.ThicknessdmEditFieldLabel.Text = 'Thickness d (µm)';
            app.ThicknessdmEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.ThicknessdmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessdmEditFieldValueChanged, true);
            app.RefractiveIndexLaserMediumnEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.RefractiveIndexLaserMediumnEditFieldLabel.Text = 'Refractive Index Laser Medium n';
            app.RefractiveIndexLaserMediumnEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.RefractiveIndexLaserMediumnEditField.ValueChangedFcn = createCallbackFcn(app, @RefractiveIndexLaserMediumnEditFieldValueChanged, true);
            app.RefractiveIndexLaserMediumnEditField.Value = 1;
            app.RefractiveIndexMediumBehindnEditFieldLabel = uilabel(app.PhysicalParametersPanel_2);
            app.RefractiveIndexMediumBehindnEditFieldLabel.Text = 'Refractive Index Medium Behind n';
            app.RefractiveIndexMediumBehindnEditField = uieditfield(app.PhysicalParametersPanel_2, 'numeric');
            app.RefractiveIndexMediumBehindnEditField.ValueChangedFcn = createCallbackFcn(app, @RefractiveIndexMediumBehindnEditFieldValueChanged, true);
            app.RefractiveIndexMediumBehindnEditField.Value = 1;
            app.AddLayerButton = uibutton(app.PhysicalParametersPanel_2, 'push');
            app.AddLayerButton.ButtonPushedFcn = createCallbackFcn(app, @AddLayerButtonPushed, true);
            app.AddLayerButton.Text = 'Add Layer';
            app.DeleteLayerButton = uibutton(app.PhysicalParametersPanel_2, 'push');
            app.DeleteLayerButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLayerButtonPushed, true);
            app.DeleteLayerButton.Text = 'Delete Layer';
            app.SimulationParametersPanel = uipanel(app.PhotonDistributionTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.NumberofPhotonsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.NumberofPhotonsEditFieldLabel.Text = 'Number of Photons';
            app.NumberofPhotonsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.NumberofPhotonsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPhotonsEditFieldValueChanged, true);
            app.dzmEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.dzmEditFieldLabel.Text = 'dz (µm)';
            app.dzmEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.dzmEditField.ValueChangedFcn = createCallbackFcn(app, @dzmEditFieldValueChanged, true);
            app.drmEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.drmEditFieldLabel.Text = 'dr (µm)';
            app.drmEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.drmEditField.ValueChangedFcn = createCallbackFcn(app, @drmEditFieldValueChanged, true);
            app.CalculatePhotonDistributionButton = uibutton(app.PhotonDistributionTab, 'push');
            app.CalculatePhotonDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePhotonDistributionButtonPushed, true);
            app.CalculatePhotonDistributionButton.Text = 'Calculate Photon Distribution';
            app.LabelPhotonCalc = uilabel(app.PhotonDistributionTab);
            app.LabelPhotonCalc.Text = 'No Result';
            app.HeatTab = uitab(app.TabGroup);
            app.HeatTab.Title = 'Heat';
            app.SetPhotonDistributionDataButton = uibutton(app.HeatTab, 'push');
            app.SetPhotonDistributionDataButton.ButtonPushedFcn = createCallbackFcn(app, @SetPhotonDistributionDataButtonPushed, true);
            app.SetPhotonDistributionDataButton.Text = 'Set Photon Distribution Data';
            app.LabelPhotonDist = uilabel(app.HeatTab);
            app.LabelPhotonDist.Text = '- No Data Set -';
            app.SaveAsButton = uibutton(app.HeatTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.SaveButton = uibutton(app.HeatTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TableSubjectData = uitable(app.HeatTab);
            app.TableSubjectData.ColumnName = {'n'; 'µa (cm^-1)'; 'µs (cm^-1)'; 'g'; 'd (µm)'};
            app.TableSubjectData.RowName = {};
            app.TableSubjectData.ColumnEditable = [false false false false false];
            app.LoadHeatButton = uibutton(app.HeatTab, 'push');
            app.LoadHeatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHeatButtonPushed, true);
            app.LoadHeatButton.Text = 'Load';
            app.LabelHeatSetting = uilabel(app.HeatTab);
            app.LabelHeatSetting.Text = '- No Data Set -';
            app.NewHeatSettingsButton = uibutton(app.HeatTab, 'push');
            app.NewHeatSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @NewHeatSettingsButtonPushed, true);
            app.NewHeatSettingsButton.Text = 'New Heat Settings';
            app.PhysicalParametersPanel = uipanel(app.HeatTab);
            app.PhysicalParametersPanel.Title = 'Physical Parameters';
            app.TableSubjectDataSet = uitable(app.PhysicalParametersPanel);
            app.TableSubjectDataSet.ColumnName = {'c (J/kg/K)'; 'rho (kg/m³)'; 'lambda (W/m/K)'};
            app.TableSubjectDataSet.RowName = {};
            app.TableSubjectDataSet.ColumnEditable = [true true true];
            app.TableSubjectDataSet.CellEditCallback = createCallbackFcn(app, @TableSubjectDataSetCellEdit, true);
            app.EvaluationTimemsEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.EvaluationTimemsEditFieldLabel.Text = 'Evaluation Time (ms)';
            app.EvaluationTimemsEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.EvaluationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @EvaluationTimemsEditFieldValueChanged, true);
            app.EvaluationTimemsEditField.Value = 100;
            app.StimulationTimemsEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.StimulationTimemsEditFieldLabel.Text = 'Stimulation Time (ms)';
            app.StimulationTimemsEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.StimulationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationTimemsEditFieldValueChanged, true);
            app.StimulationTimemsEditField.Value = 1000;
            app.PulseDurationmsEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.PulseDurationmsEditFieldLabel.Text = 'Pulse Duration (ms)';
            app.PulseDurationmsEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.PulseDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDurationmsEditFieldValueChanged, true);
            app.PulseFrequencyHzEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.PulseFrequencyHzEditFieldLabel.Text = 'Pulse Frequency (Hz)';
            app.PulseFrequencyHzEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.PulseFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseFrequencyHzEditFieldValueChanged, true);
            app.PulsePowermWEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.PulsePowermWEditFieldLabel.Text = 'Pulse Power (mW)';
            app.PulsePowermWEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.PulsePowermWEditField.ValueChangedFcn = createCallbackFcn(app, @PulsePowermWEditFieldValueChanged, true);
            app.PulsePowermWEditField.Value = 30;
            app.TambientKEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.TambientKEditFieldLabel.Text = 'Tambient (K)';
            app.TambientKEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.TambientKEditField.ValueChangedFcn = createCallbackFcn(app, @TambientKEditFieldValueChanged, true);
            app.EvaluationRadiusmLabel = uilabel(app.PhysicalParametersPanel);
            app.EvaluationRadiusmLabel.Text = 'Evaluation Radius (µm)';
            app.EvaluationRadiusmEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.EvaluationRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @EvaluationRadiusmEditFieldValueChanged, true);
            app.EvaluationThicknessmEditFieldLabel = uilabel(app.PhysicalParametersPanel);
            app.EvaluationThicknessmEditFieldLabel.Text = 'Evaluation Thickness (µm)';
            app.EvaluationThicknessmEditField = uieditfield(app.PhysicalParametersPanel, 'numeric');
            app.EvaluationThicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @EvaluationThicknessmEditFieldValueChanged, true);
            app.BoundaryConditionDropDownLabel = uilabel(app.PhysicalParametersPanel);
            app.BoundaryConditionDropDownLabel.Text = 'Boundary Condition';
            app.BoundaryConditionDropDown = uidropdown(app.PhysicalParametersPanel);
            app.BoundaryConditionDropDown.Items = {'None', 'Convection'};
            app.BoundaryConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionDropDownValueChanged, true);
            app.BoundaryConditionDropDown.Value = 'None';
            app.NumericalParametersPanel = uipanel(app.HeatTab);
            app.NumericalParametersPanel.Title = 'Numerical Parameters';
            app.MaxGridSizemEditFieldLabel = uilabel(app.NumericalParametersPanel);
            app.MaxGridSizemEditFieldLabel.Text = 'Max Grid Size (µm)';
            app.MaxGridSizemEditField = uieditfield(app.NumericalParametersPanel, 'numeric');
            app.MaxGridSizemEditField.ValueChangedFcn = createCallbackFcn(app, @MaxGridSizemEditFieldValueChanged, true);
            app.SubjectRadiusLabel = uilabel(app.HeatTab);
            app.SubjectRadiusLabel.Text = 'Subject Radius (µm)';
            app.SubjectDepthLabel = uilabel(app.HeatTab);
            app.SubjectDepthLabel.Text = 'Subject Thickness (µm)';
            app.SolveHeatEquationButton = uibutton(app.HeatTab, 'push');
            app.SolveHeatEquationButton.ButtonPushedFcn = createCallbackFcn(app, @SolveHeatEquationButtonPushed, true);
            app.SolveHeatEquationButton.Text = 'Solve Heat Equation';
            app.LabelHeatResult = uilabel(app.HeatTab);
            app.LabelHeatResult.Text = '- No Data -';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.PlottingSettingsPanel = uipanel(app.VisualizationTab);
            app.PlottingSettingsPanel.Title = 'Plotting Settings';
            app.LoadVisualizationSettingsButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.LoadVisualizationSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVisualizationSettingsButtonPushed, true);
            app.LoadVisualizationSettingsButton.Text = 'Load Visualization Settings';
            app.AvailablePlotsListBoxLabel = uilabel(app.PlottingSettingsPanel);
            app.AvailablePlotsListBoxLabel.Text = 'Available Plots';
            app.AvailablePlotsListBox = uilistbox(app.PlottingSettingsPanel);
            app.AvailablePlotsListBox.Items = {};
            app.AvailablePlotsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailablePlotsListBoxValueChanged, true);
            app.AvailablePlotsListBox.Value = {};
            app.NewButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DeleteButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GenerateallPlotsButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.GenerateallPlotsButton.Text = 'Generate all Plots';
            app.SaveAsButton_2 = uibutton(app.PlottingSettingsPanel, 'push');
            app.SaveAsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButton_2Pushed, true);
            app.SaveAsButton_2.Text = 'Save As...';
            app.NewVisualizationSettingsButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.NewVisualizationSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @NewVisualizationSettingsButtonPushed, true);
            app.NewVisualizationSettingsButton.Text = 'New Visualization Settings';
            app.SelectheatresultdataButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.SelectheatresultdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectheatresultdataButtonPushed, true);
            app.SelectheatresultdataButton.Text = 'Select heat result data';
            app.LoadButton = uibutton(app.PlottingSettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LabelVisualizationSetting = uilabel(app.PlottingSettingsPanel);
            app.LabelVisualizationSetting.Text = '- No Data Set - ';
            app.LabelHeatDataSet = uilabel(app.PlottingSettingsPanel);
            app.LabelHeatDataSet.Text = '- No Data Set -';
            app.SaveVisualization = uibutton(app.PlottingSettingsPanel, 'push');
            app.SaveVisualization.ButtonPushedFcn = createCallbackFcn(app, @SaveVisualizationButtonPushed, true);
            app.SaveVisualization.Text = 'Save';
            app.PlotPropertiesPanel = uipanel(app.VisualizationTab);
            app.PlotPropertiesPanel.Title = 'Plot Properties';
            app.xAxisButtonGroup = uibuttongroup(app.PlotPropertiesPanel);
            app.xAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xAxisButtonGroupSelectionChanged, true);
            app.xAxisButtonGroup.Title = 'x-Axis';
            app.RadiusrButton = uiradiobutton(app.xAxisButtonGroup);
            app.RadiusrButton.Text = 'Radius r';
            app.RadiusrButton.Value = true;
            app.DepthzButton = uiradiobutton(app.xAxisButtonGroup);
            app.DepthzButton.Text = 'Depth z';
            app.TimetButton = uiradiobutton(app.xAxisButtonGroup);
            app.TimetButton.Text = 'Time t';
            app.MultiplePlotsListBoxLabel = uilabel(app.PlotPropertiesPanel);
            app.MultiplePlotsListBoxLabel.Text = 'Multiple Plots';
            app.MultiplePlotsListBox = uilistbox(app.PlotPropertiesPanel);
            app.MultiplePlotsListBox.ValueChangedFcn = createCallbackFcn(app, @MultiplePlotsListBoxValueChanged, true);
            app.NewButton_2 = uibutton(app.PlotPropertiesPanel, 'push');
            app.NewButton_2.ButtonPushedFcn = createCallbackFcn(app, @NewButton_2Pushed, true);
            app.NewButton_2.Text = 'New';
            app.DeleteButton_2 = uibutton(app.PlotPropertiesPanel, 'push');
            app.DeleteButton_2.Text = 'Delete';
            app.IncludePlotinGenerationCheckBox = uicheckbox(app.PlotPropertiesPanel);
            app.IncludePlotinGenerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludePlotinGenerationCheckBoxValueChanged, true);
            app.IncludePlotinGenerationCheckBox.Text = 'Include Plot in Generation';
            app.xAxisupperLimitEditFieldLabel = uilabel(app.PlotPropertiesPanel);
            app.xAxisupperLimitEditFieldLabel.Text = 'x-Axis upper Limit';
            app.xAxisupperLimitEditField = uieditfield(app.PlotPropertiesPanel, 'numeric');
            app.xAxisupperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @xAxisupperLimitEditFieldValueChanged, true);
            app.xAxislowerLimitEditFieldLabel = uilabel(app.PlotPropertiesPanel);
            app.xAxislowerLimitEditFieldLabel.Text = 'x-Axis lower Limit';
            app.xAxislowerLimitEditField = uieditfield(app.PlotPropertiesPanel, 'numeric');
            app.xAxislowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @xAxislowerLimitEditFieldValueChanged, true);
            app.TAxisupperLimitEditFieldLabel = uilabel(app.PlotPropertiesPanel);
            app.TAxisupperLimitEditFieldLabel.Text = 'T-Axis upper Limit';
            app.TAxisupperLimitEditField = uieditfield(app.PlotPropertiesPanel, 'numeric');
            app.TAxisupperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TAxisupperLimitEditFieldValueChanged, true);
            app.TAxislowerLimitLabel = uilabel(app.PlotPropertiesPanel);
            app.TAxislowerLimitLabel.Text = 'T-Axis lower Limit';
            app.TAxislowerLimitEditField = uieditfield(app.PlotPropertiesPanel, 'numeric');
            app.TAxislowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TAxislowerLimitEditFieldValueChanged, true);
            app.xAxUpAuto = uicheckbox(app.PlotPropertiesPanel);
            app.xAxUpAuto.ValueChangedFcn = createCallbackFcn(app, @xAxUpAutoValueChanged, true);
            app.xAxUpAuto.Text = 'Auto';
            app.TAxLowAuto = uicheckbox(app.PlotPropertiesPanel);
            app.TAxLowAuto.ValueChangedFcn = createCallbackFcn(app, @TAxLowAutoValueChanged, true);
            app.TAxLowAuto.Text = 'Auto';
            app.NameEditFieldLabel = uilabel(app.PlotPropertiesPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.PlotPropertiesPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.UpdatePreviewButton = uibutton(app.PlotPropertiesPanel, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.xAxLowAuto = uicheckbox(app.PlotPropertiesPanel);
            app.xAxLowAuto.ValueChangedFcn = createCallbackFcn(app, @xAxLowAutoValueChanged, true);
            app.xAxLowAuto.Text = 'Auto';
            app.TAxUpAuto = uicheckbox(app.PlotPropertiesPanel);
            app.TAxUpAuto.ValueChangedFcn = createCallbackFcn(app, @TAxUpAutoValueChanged, true);
            app.TAxUpAuto.Text = 'Auto';
            app.ValueSection1 = uieditfield(app.PlotPropertiesPanel, 'numeric');
            app.ValueSection1.ValueChangedFcn = createCallbackFcn(app, @ValueSection1ValueChanged, true);
            app.ValueSection2 = uieditfield(app.PlotPropertiesPanel, 'numeric');
            app.ValueSection2.ValueChangedFcn = createCallbackFcn(app, @ValueSection2ValueChanged, true);
            app.LabelSection1 = uilabel(app.PlotPropertiesPanel);
            app.LabelSection1.Text = 'z =';
            app.LabelSection2 = uilabel(app.PlotPropertiesPanel);
            app.LabelSection2.Text = 't =';
            app.UnitSection1 = uilabel(app.PlotPropertiesPanel);
            app.UnitSection1.Text = 'µm';
            app.UnitSection2 = uilabel(app.PlotPropertiesPanel);
            app.UnitSection2.Text = 'ms';
            app.PlotPreviewPanel = uipanel(app.VisualizationTab);
            app.PlotPreviewPanel.Title = 'Plot Preview';
            app.UIAxes = uiaxes(app.PlotPreviewPanel);
            title(app.UIAxes, 'eTitle')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DebugPanel = uipanel(app.LaTInt);
            app.DebugPanel.Title = 'Debug';
            app.DirApp = uilabel(app.DebugPanel);
            app.DirData = uilabel(app.DebugPanel);
            app.DirData.Text = 'Label2';
            app.DirectoryAppLabel = uilabel(app.DebugPanel);
            app.DirectoryAppLabel.Text = 'Directory App';
            app.DirectoryDataLabel = uilabel(app.DebugPanel);
            app.DirectoryDataLabel.Text = 'Directory Data';
            % Show the figure after all components are created
            app.LaTInt.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laserapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaTInt)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaTInt)
        end
    end
end